classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Image1Tab                matlab.ui.container.Tab
        recommendvalue074Label   matlab.ui.control.Label
        methodButtonGroup        matlab.ui.container.ButtonGroup
        connectivity4Button      matlab.ui.control.RadioButton
        connectivity8Button_2    matlab.ui.control.RadioButton
        thresholdEditField       matlab.ui.control.NumericEditField
        thresholdEditFieldLabel  matlab.ui.control.Label
        angleEditField           matlab.ui.control.NumericEditField
        angleEditFieldLabel      matlab.ui.control.Label
        rotateButton             matlab.ui.control.Button
        labelButton              matlab.ui.control.Button
        OutlineButton            matlab.ui.control.Button
        onepixelimageButton      matlab.ui.control.Button
        binaryimageButton        matlab.ui.control.Button
        originalimageButton      matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        Image2Tab                matlab.ui.container.Tab
        methodButtonGroup_2      matlab.ui.container.ButtonGroup
        connectivity4Button_2    matlab.ui.control.RadioButton
        connectivity8Button_3    matlab.ui.control.RadioButton
        subimageButton           matlab.ui.control.Button
        labelButton_2            matlab.ui.control.Button
        OutlineButton_2          matlab.ui.control.Button
        onepixelimageButton_2    matlab.ui.control.Button
        binaryimageButton_2      matlab.ui.control.Button
        originalimageButton_2    matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        ImageprocessingAPPLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: originalimageButton
        function originalimageButtonPushed(app, event)
                filepath = 'chromo.txt';
                [original_image] = Display_original(filepath);
                imshow(original_image,'Parent',app.UIAxes);
        end

        % Button pushed function: binaryimageButton
        function binaryimageButtonPushed(app, event)
                filepath = 'result\original_image_1.jpg';
                threshold=app.thresholdEditField.Value
                [binary_image] = Adaptthresh_MATLAB(filepath,threshold);
                imshow(binary_image,'Parent',app.UIAxes);
                imwrite(binary_image,'result\binary_image_1.tif')
        end

        % Button pushed function: onepixelimageButton
        function onepixelimageButtonPushed(app, event)
                binary_image=imread('result\binary_image_1.tif');
                binary_image1=bwmorph(binary_image,"bridge");
                binary_image2=bwmorph(binary_image1,"fill");
                img=single(binary_image2);
                ThinClass=ZhangSuenThin(img);
                imgThin=ThinClass.thinImg;
                imshow(imgThin,'Parent',app.UIAxes)
        end

        % Button pushed function: OutlineButton
        function OutlineButtonPushed(app, event)
                binary_image=imread('result\binary_image_1.tif');
                binary_image1=bwmorph(binary_image,"bridge");
                binary_image2=bwmorph(binary_image1,"fill");
                img=single(binary_image2);
                OutlineClass=CannyEdgeDetection(3,1,img);
                imgOutline=OutlineClass.outlineImg;
                imshow(mat2gray(imgOutline),'Parent',app.UIAxes)
        end

        % Button pushed function: labelButton
        function labelButtonPushed(app, event)
                binary_image=imread('result\binary_image_1.tif');
                selectedButton=app.methodButtonGroup.SelectedObject;
                if isequal(get(selectedButton,'text'),'connectivity 4')
                    matrix_region_4=regiongrow_4(binary_image);
                    matrix_region_4_RGB = label2rgb(matrix_region_4,'hsv',[0 0 0]);
                    imshow(matrix_region_4_RGB,'Parent',app.UIAxes);
                end
                if isequal(get(selectedButton,'text'),'connectivity 8')
                    matrix_region_8=regiongrow_8(binary_image);
                    matrix_region_8_RGB = label2rgb(matrix_region_8,'hsv',[0 0 0]);
                    imshow(matrix_region_8_RGB,'Parent',app.UIAxes);
                end
        end

        % Button pushed function: rotateButton
        function rotateButtonPushed(app, event)
                original_image=imread('result\original_image_1.jpg');
                angle=app.angleEditField.Value;
                rot_bicubic=imrotate(original_image,-angle,'bicubic');
                imshow(rot_bicubic,'Parent',app.UIAxes);
        end

        % Size changed function: Image2Tab
        function Image2TabSizeChanged(app, event)
            position = app.Image2Tab.Position;
        end

        % Button pushed function: originalimageButton_2
        function originalimageButton_2Pushed(app, event)
                original_image = imread('hello_world.jpg');
                imshow(original_image,'Parent',app.UIAxes_2);
        end

        % Button pushed function: subimageButton
        function subimageButtonPushed(app, event)
                filepath = 'result\original_image_2.jpg';
                [sub_image]=cut_sub_image(filepath);
                imshow(sub_image,'Parent',app.UIAxes_2);       
        end

        % Button pushed function: binaryimageButton_2
        function binaryimageButton_2Pushed(app, event)
                filepath='result\original_sub_image_2.jpg';
                [binary_image] = Global_thresholding(filepath);
                imshow(binary_image,'Parent',app.UIAxes_2);
        end

        % Button pushed function: onepixelimageButton_2
        function onepixelimageButton_2Pushed(app, event)
                filepath='result\original_sub_image_2.jpg';
                [binary_image] = Global_thresholding(filepath);
                img=single(binary_image);
                ThinClass=ZhangSuenThin(img);
                imgThin=ThinClass.thinImg;
                imshow(imgThin,'Parent',app.UIAxes_2)
        end

        % Button pushed function: OutlineButton_2
        function OutlineButton_2Pushed(app, event)
                filepath='result\original_sub_image_2.jpg';
                [binary_image] = Global_thresholding(filepath);
                img=single(binary_image);
                OutlineClass=CannyEdgeDetection(3,1,img);
                imgOutline=OutlineClass.outlineImg;
                imshow(mat2gray(imgOutline),'Parent',app.UIAxes_2)
        end

        % Button pushed function: labelButton_2
        function labelButton_2Pushed(app, event)
                filepath='result\original_sub_image_2.jpg';
                [binary_image] = Global_thresholding(filepath);
                for i=1:3
                   binary_image(:,i)=[];
                end
                matrix_region_4=regiongrow_4(binary_image);
                matrix_region_4_RGB = label2rgb(matrix_region_4,'hsv',[0 0 0],'shuffle');
                imshow(matrix_region_4_RGB,'Parent',app.UIAxes_2);
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)
            value = app.thresholdEditField.Value;
            
        end

        % Selection changed function: methodButtonGroup
        function methodButtonGroupSelectionChanged(app, event)
            selectedButton = app.methodButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageprocessingAPPLabel = uilabel(app.UIFigure);
            app.ImageprocessingAPPLabel.Text = 'Image processing APP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Image1Tab = uitab(app.TabGroup);
            app.Image1Tab.Title = 'Image 1';
            app.UIAxes = uiaxes(app.Image1Tab);
            title(app.UIAxes, 'Image 1')
            app.originalimageButton = uibutton(app.Image1Tab, 'push');
            app.originalimageButton.ButtonPushedFcn = createCallbackFcn(app, @originalimageButtonPushed, true);
            app.originalimageButton.Text = ' original image';
            app.binaryimageButton = uibutton(app.Image1Tab, 'push');
            app.binaryimageButton.ButtonPushedFcn = createCallbackFcn(app, @binaryimageButtonPushed, true);
            app.binaryimageButton.Text = ' binary image';
            app.onepixelimageButton = uibutton(app.Image1Tab, 'push');
            app.onepixelimageButton.ButtonPushedFcn = createCallbackFcn(app, @onepixelimageButtonPushed, true);
            app.onepixelimageButton.Text = 'one-pixel image';
            app.OutlineButton = uibutton(app.Image1Tab, 'push');
            app.OutlineButton.ButtonPushedFcn = createCallbackFcn(app, @OutlineButtonPushed, true);
            app.OutlineButton.Text = 'Outline';
            app.labelButton = uibutton(app.Image1Tab, 'push');
            app.labelButton.ButtonPushedFcn = createCallbackFcn(app, @labelButtonPushed, true);
            app.labelButton.Text = 'label';
            app.rotateButton = uibutton(app.Image1Tab, 'push');
            app.rotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateButtonPushed, true);
            app.rotateButton.Text = 'rotate';
            app.angleEditFieldLabel = uilabel(app.Image1Tab);
            app.angleEditFieldLabel.Text = 'angle';
            app.angleEditField = uieditfield(app.Image1Tab, 'numeric');
            app.thresholdEditFieldLabel = uilabel(app.Image1Tab);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.Image1Tab, 'numeric');
            app.thresholdEditField.Limits = [0 1];
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.methodButtonGroup = uibuttongroup(app.Image1Tab);
            app.methodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @methodButtonGroupSelectionChanged, true);
            app.methodButtonGroup.Title = 'method';
            app.connectivity8Button_2 = uiradiobutton(app.methodButtonGroup);
            app.connectivity8Button_2.Text = 'connectivity 8';
            app.connectivity8Button_2.Value = true;
            app.connectivity4Button = uiradiobutton(app.methodButtonGroup);
            app.connectivity4Button.Text = 'connectivity 4';
            app.recommendvalue074Label = uilabel(app.Image1Tab);
            app.recommendvalue074Label.Text = 'recommend value:0.74';
            app.Image2Tab = uitab(app.TabGroup);
            app.Image2Tab.SizeChangedFcn = createCallbackFcn(app, @Image2TabSizeChanged, true);
            app.Image2Tab.Title = 'Image 2';
            app.UIAxes_2 = uiaxes(app.Image2Tab);
            title(app.UIAxes_2, 'Image 2')
            app.originalimageButton_2 = uibutton(app.Image2Tab, 'push');
            app.originalimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @originalimageButton_2Pushed, true);
            app.originalimageButton_2.Text = ' original image';
            app.binaryimageButton_2 = uibutton(app.Image2Tab, 'push');
            app.binaryimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @binaryimageButton_2Pushed, true);
            app.binaryimageButton_2.Text = ' binary image';
            app.onepixelimageButton_2 = uibutton(app.Image2Tab, 'push');
            app.onepixelimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @onepixelimageButton_2Pushed, true);
            app.onepixelimageButton_2.Text = 'one-pixel image';
            app.OutlineButton_2 = uibutton(app.Image2Tab, 'push');
            app.OutlineButton_2.ButtonPushedFcn = createCallbackFcn(app, @OutlineButton_2Pushed, true);
            app.OutlineButton_2.Text = 'Outline';
            app.labelButton_2 = uibutton(app.Image2Tab, 'push');
            app.labelButton_2.ButtonPushedFcn = createCallbackFcn(app, @labelButton_2Pushed, true);
            app.labelButton_2.Text = 'label';
            app.subimageButton = uibutton(app.Image2Tab, 'push');
            app.subimageButton.ButtonPushedFcn = createCallbackFcn(app, @subimageButtonPushed, true);
            app.subimageButton.Text = 'sub-image';
            app.methodButtonGroup_2 = uibuttongroup(app.Image2Tab);
            app.methodButtonGroup_2.Title = 'method';
            app.connectivity8Button_3 = uiradiobutton(app.methodButtonGroup_2);
            app.connectivity8Button_3.Text = 'connectivity 8';
            app.connectivity8Button_3.Value = true;
            app.connectivity4Button_2 = uiradiobutton(app.methodButtonGroup_2);
            app.connectivity4Button_2.Text = 'connectivity 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end